classdef GH_GraphHopperGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ExportJSONDataPanel             matlab.ui.container.Panel
        ExportVariableEditField         matlab.ui.control.EditField
        VariableNameEditFieldLabel      matlab.ui.control.Label
        ExportDataCheckBox              matlab.ui.control.CheckBox
        ImportJSONDataPanel             matlab.ui.container.Panel
        ImportFileButton                matlab.ui.control.Button
        SelectFileButton                matlab.ui.control.Button
        ImportFileEditField             matlab.ui.control.EditField
        FileEditFieldLabel              matlab.ui.control.Label
        ApiKeyEditField                 matlab.ui.control.EditField
        ApiKeyEditFieldLabel            matlab.ui.control.Label
        LanguageDropDown                matlab.ui.control.DropDown
        LanguageDropDownLabel           matlab.ui.control.Label
        RoutingPanel                    matlab.ui.container.Panel
        ExportFile                      matlab.ui.control.Button
        IncludeLaneInformationCheckBox  matlab.ui.control.CheckBox
        IncludeRoadClassCheckBox        matlab.ui.control.CheckBox
        IncludeMaxSpeedCheckBox         matlab.ui.control.CheckBox
        IncludeWaypointsElevationCheckBox  matlab.ui.control.CheckBox
        VehicleTypeDropDown             matlab.ui.control.DropDown
        VehicleTypeDropDownLabel        matlab.ui.control.Label
        ResetRoutingButton              matlab.ui.control.Button
        GetRouteButton                  matlab.ui.control.Button
        LocationTabGroup                matlab.ui.container.TabGroup
        CoordinatesTab                  matlab.ui.container.Tab
        FlipCoordinatesButton           matlab.ui.control.Button
        CopyEndCoordButton              matlab.ui.control.Button
        CopyStartCoordButton            matlab.ui.control.Button
        ResetCoordButton                matlab.ui.control.Button
        EndCoordEditField               matlab.ui.control.EditField
        EndCoordEditFieldLabel          matlab.ui.control.Label
        StartCoordEditField             matlab.ui.control.EditField
        StartCoordEditFieldLabel        matlab.ui.control.Label
        AddressTab                      matlab.ui.container.Tab
        FlipAddressButton               matlab.ui.control.Button
        ResetAddressButton              matlab.ui.control.Button
        GetCoordinatesButton            matlab.ui.control.Button
        EndAddressEditField             matlab.ui.control.EditField
        EndAddressEditFieldLabel        matlab.ui.control.Label
        StartAddressEditField           matlab.ui.control.EditField
        StartAddressEditFieldLabel      matlab.ui.control.Label
        PlotDataUIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        DefaultConfigStruct % Struct with the default configuration of all app data

        RouteJsonDataStr % String with the route json data

        RouteDataStruct % Struct with the route json data
    end

    methods (Access = private)

        function pointData = GetPointCoordinatesFromAddress(app, addressStr, addressType)
            % Call the GraphHopper API to convert the address into its coordinates point [lat,lng]

            % Initialize outputs
            pointData = [];

            % Call Geocoding API
            [fcnStatus, fcnMsg, rawDataStr] = GHAPI_Geocoding(addressStr, ...
                app.ApiKeyEditField.Value, app.LanguageDropDown.Value);
            if (fcnStatus < 0)
                % An error occurred, abort
                uialert(app.UIFigure, fcnMsg, addressType, 'Icon', 'error');
                uiwait(gcbf);
                return
            end

            % Convert json data into a struct
            jsonDataStruct = jsondecode(rawDataStr);
            % Parse json data struct
            [fcnStatus, fcnMsg, pointData] = GH_ParseGeocodingData(jsonDataStruct);
            if (fcnStatus < 0)
                % An error occurred, abort
                uialert(app.UIFigure, fcnMsg, 'Start Address', 'Icon', 'error');
                uiwait(gcbf);
                return
            elseif (fcnStatus < 1)
                % A warning occured, print it and continue
                uialert(app.UIFigure, fcnMsg, 'Start Address', 'Icon', 'warning');
                uiwait(gcbf);
            end
        end


        function DrawWaypointsData(app)
            % Parse json data struct
            [fcnStatus, fcnMsg, waypointsData] = GH_ParseRoutingData(app.RouteDataStruct);
            if (fcnStatus < 0)
                % An error occurred, abort
                uialert(app.UIFigure, fcnMsg, 'Invalid Routing data', 'Icon', 'error');
                uiwait(gcbf);
                return
            end

            % Draw waypoints data
            plot(app.PlotDataUIAxes, waypointsData(:,1), waypointsData(:,2), 'b-o');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Address data
            app.DefaultConfigStruct.StartAddress = app.StartAddressEditField.Value;
            app.DefaultConfigStruct.EndAddress = app.EndAddressEditField.Value;
            % Coordinates data
            app.DefaultConfigStruct.StartCoord = app.StartCoordEditField.Value;
            app.DefaultConfigStruct.EndCoord = app.EndCoordEditField.Value;
            % Routing options
            app.DefaultConfigStruct.VehicleType = app.VehicleTypeDropDown.Value;
            app.DefaultConfigStruct.IncludeWaypointsElevation = ...
                app.IncludeWaypointsElevationCheckBox.Value;
            app.DefaultConfigStruct.IncludeRoadClass = app.IncludeRoadClassCheckBox.Value;
            app.DefaultConfigStruct.IncludeMaxSpeed = app.IncludeMaxSpeedCheckBox.Value;
            app.DefaultConfigStruct.IncludeLaneInformation = app.IncludeLaneInformationCheckBox.Value;

            % Route Json Data
            app.RouteJsonDataStr = '';
            app.RouteDataStruct = [];
        end

        % Button pushed function: GetCoordinatesButton
        function GetCoordinatesButtonPushed(app, event)
            % Initialize success flag
            bSuccess = true;

            % Get start point coordinates
            pointData = app.GetPointCoordinatesFromAddress(app.StartAddressEditField.Value, ...
                'Start Address');
            if isempty(pointData)
                bSuccess = false;
            else
                app.StartCoordEditField.Value = [num2str(pointData(1), '%.7f') ',' ...
                    num2str(pointData(2), '%.7f')];
            end

            % Get end point coordinates
            pointData = app.GetPointCoordinatesFromAddress(app.EndAddressEditField.Value, ...
                'End Address');
            if isempty(pointData)
                bSuccess = false;
            else
                app.EndCoordEditField.Value = [num2str(pointData(1), '%.7f') ',' ...
                    num2str(pointData(2), '%.7f')];
            end

            % Switch to the coordinates tab
            app.LocationTabGroup.SelectedTab = app.CoordinatesTab;
            if bSuccess
                fcnMsg = 'Coordinates of the input addresses were determined successfully!';
                uialert(app.UIFigure, fcnMsg, 'Get Coordinates', 'Icon', 'success');
                uiwait(gcbf);
            end
        end

        % Button pushed function: ResetAddressButton
        function ResetAddressButtonPushed(app, event)
            % Reset address data
            app.StartAddressEditField.Value = app.DefaultConfigStruct.StartAddress;
            app.EndAddressEditField.Value = app.DefaultConfigStruct.EndAddress;
        end

        % Value changed function: StartAddressEditField
        function StartAddressEditFieldValueChanged(app, event)

        end

        % Button pushed function: ResetCoordButton
        function ResetCoordButtonPushed(app, event)
            % Reset coordinates data
            app.StartCoordEditField.Value = app.DefaultConfigStruct.StartCoord;
            app.EndCoordEditField.Value = app.DefaultConfigStruct.EndCoord;
        end

        % Button pushed function: CopyStartCoordButton
        function CopyStartCoordButtonPushed(app, event)
            % Copy start point to clipboard
            clipboard('copy', app.StartCoordEditField.Value);
            uialert(app.UIFigure, 'Start point copied to clipboard!', 'Coordinates', 'Icon', 'info');
            uiwait(gcbf);
        end

        % Button pushed function: CopyEndCoordButton
        function CopyEndCoordButtonPushed(app, event)
            % Copy end point to clipboard
            clipboard('copy', app.EndCoordEditField.Value);
            uialert(app.UIFigure, 'End point copied to clipboard!', 'Coordinates', 'Icon', 'info');
            uiwait(gcbf);
        end

        % Button pushed function: ResetRoutingButton
        function ResetRoutingButtonPushed(app, event)
            % Reset Routing options
            app.VehicleTypeDropDown.Value = app.DefaultConfigStruct.VehicleType;
            app.IncludeWaypointsElevationCheckBox.Value = ...
                app.DefaultConfigStruct.IncludeWaypointsElevation;
            app.IncludeRoadClassCheckBox.Value = app.DefaultConfigStruct.IncludeRoadClass;
            app.IncludeMaxSpeedCheckBox.Value = app.DefaultConfigStruct.IncludeMaxSpeed;
            app.IncludeLaneInformationCheckBox.Value = app.DefaultConfigStruct.IncludeLaneInformation;
        end

        % Button pushed function: GetRouteButton
        function GetRouteButtonPushed(app, event)
            if (app.LocationTabGroup.SelectedTab ~= app.CoordinatesTab)
                % Notify user to get the coordinates first
                fcnMsg = 'Get coordinates first before calling this!';
                uialert(app.UIFigure, fcnMsg, 'Routing', 'Icon', 'warning');
                uiwait(gcbf);
                return
            end

            % Get routing options
            optionsDataCell = {'Language', app.LanguageDropDown.Value, ...
                'VehicleType',  app.VehicleTypeDropDown.Value, ...
                'bHasElevation', app.IncludeWaypointsElevationCheckBox.Value, ...
                'bHasRoadClass', app.IncludeRoadClassCheckBox.Value, ...
                'bHasMaxSpeed', app.IncludeMaxSpeedCheckBox.Value, ...
                'bHasLaneInfo', app.IncludeLaneInformationCheckBox.Value};
            optionsMap = containers.Map(optionsDataCell(1:2:end), optionsDataCell(2:2:end));

            % Get routing information from Graph Hopper
            [fcnStatus, fcnMsg, rawDataStr] = GHAPI_Routing(app.StartCoordEditField.Value, ...
                app.EndCoordEditField.Value, app.ApiKeyEditField.Value, optionsMap);
            if (fcnStatus < 0)
                % An error occurred, abort
                uialert(app.UIFigure, fcnMsg, 'Invalid Routing', 'Icon', 'error');
                uiwait(gcbf);
                return
            end
            % Save route
            app.RouteJsonDataStr = rawDataStr;
            % Convert json data into a struct
            app.RouteDataStruct = jsondecode(app.RouteJsonDataStr);

            % Export to base workspace if needed
            if (app.ExportDataCheckBox.Value)
                assignin('base', app.ExportVariableEditField.Value, app.RouteDataStruct);
            end

            % Parse json data struct and draw waypoints data
            app.DrawWaypointsData();
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            [fileName, dirName, idx] = uigetfile('*.json', 'Select the .json data file:');
            if (idx == 0)
                % User pressed cancel, abort
                return
            end
            app.ImportFileEditField.Value = fullfile(dirName, fileName);
        end

        % Button pushed function: ExportFile
        function ExportFilePushed(app, event)
            if isempty(app.RouteJsonDataStr)
                uialert('No route data exists, cannot export to .json file!', 'Export Route data', 'Icon', 'warning');
                uiwait(gcbf);
                return
            end
            [fileName, dirPath, idx] = uiputfile('*.json','Please enter the file path:','GH_Route.json');
            if (idx == 0)
                % User pressed cancel, return
                return
            end

            % Export (and overwrite) data to file
            jsonFilePath = fullfile(dirPath, fileName);
            fileId = fopen(jsonFilePath,'w');
            fprintf(fileId, '%s', app.RouteJsonDataStr);
            fclose(fileId);
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            if isempty(app.ImportFileEditField.Value)
                % Invoke JSON file select button callback
                app.SelectFileButtonPushed();
            end

            % Import data
            rawDataStr = fileread(app.ImportFileEditField.Value);
            % Save imported data
            app.RouteJsonDataStr = rawDataStr;
            app.RouteDataStruct = jsondecode(app.RouteJsonDataStr);

            % Export to base workspace if needed
            if (app.ExportDataCheckBox.Value)
                assignin('base', app.ExportVariableEditField.Value, app.RouteDataStruct);
            end

            % Parse json data struct and draw waypoints data
            app.DrawWaypointsData();
        end

        % Button pushed function: FlipAddressButton
        function FlipAddressButtonPushed(app, event)
            tmpStr = app.EndAddressEditField.Value;
            app.EndAddressEditField.Value = app.StartAddressEditField.Value;
            app.StartAddressEditField.Value = tmpStr;
        end

        % Button pushed function: FlipCoordinatesButton
        function FlipCoordinatesButtonPushed(app, event)
            tmpStr = app.EndCoordEditField.Value;
            app.EndCoordEditField.Value = app.StartCoordEditField.Value;
            app.StartCoordEditField.Value = tmpStr;
        end

        % Value changed function: ExportVariableEditField
        function ExportVariableEditFieldValueChanged(app, event)
            if ~isvarname(app.ExportVariableEditField.Value)
                app.ExportVariableEditField.Value = 'routeDataStruct';
                fcnMsg = 'Invalid variable name! Reverting to the default name...';
                uialert(app.UIFigure, fcnMsg, 'Export to workspace', 'Icon', 'error');
                uiwait(gcbf);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Graph Hopper GUI';
            app.PlotDataUIAxes = uiaxes(app.UIFigure);
            title(app.PlotDataUIAxes, 'Plot 2-D Map Data')
            xlabel(app.PlotDataUIAxes, 'xPosition')
            ylabel(app.PlotDataUIAxes, 'yPosition')
            zlabel(app.PlotDataUIAxes, 'zPosition')
            app.LocationTabGroup = uitabgroup(app.UIFigure);
            app.CoordinatesTab = uitab(app.LocationTabGroup);
            app.CoordinatesTab.Title = 'Coordinates';
            app.StartCoordEditFieldLabel = uilabel(app.CoordinatesTab);
            app.StartCoordEditFieldLabel.Text = 'Start';
            app.StartCoordEditField = uieditfield(app.CoordinatesTab, 'text');
            app.StartCoordEditField.Value = '48.0821586,11.6316955';
            app.EndCoordEditFieldLabel = uilabel(app.CoordinatesTab);
            app.EndCoordEditFieldLabel.Text = 'End';
            app.EndCoordEditField = uieditfield(app.CoordinatesTab, 'text');
            app.EndCoordEditField.Value = '48.0523249,11.6001184';
            app.ResetCoordButton = uibutton(app.CoordinatesTab, 'push');
            app.ResetCoordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCoordButtonPushed, true);
            app.ResetCoordButton.Text = 'Reset';
            app.CopyStartCoordButton = uibutton(app.CoordinatesTab, 'push');
            app.CopyStartCoordButton.ButtonPushedFcn = createCallbackFcn(app, @CopyStartCoordButtonPushed, true);
            app.CopyStartCoordButton.Text = 'Copy Start';
            app.CopyEndCoordButton = uibutton(app.CoordinatesTab, 'push');
            app.CopyEndCoordButton.ButtonPushedFcn = createCallbackFcn(app, @CopyEndCoordButtonPushed, true);
            app.CopyEndCoordButton.Text = 'Copy End';
            app.FlipCoordinatesButton = uibutton(app.CoordinatesTab, 'push');
            app.FlipCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @FlipCoordinatesButtonPushed, true);
            app.FlipCoordinatesButton.Text = '↕';
            app.AddressTab = uitab(app.LocationTabGroup);
            app.AddressTab.Title = 'Address';
            app.StartAddressEditFieldLabel = uilabel(app.AddressTab);
            app.StartAddressEditFieldLabel.Text = 'Start';
            app.StartAddressEditField = uieditfield(app.AddressTab, 'text');
            app.StartAddressEditField.ValueChangedFcn = createCallbackFcn(app, @StartAddressEditFieldValueChanged, true);
            app.StartAddressEditField.Value = 'Zwergerstr. 31, 85579 Neubiberg';
            app.EndAddressEditFieldLabel = uilabel(app.AddressTab);
            app.EndAddressEditFieldLabel.Text = 'End';
            app.EndAddressEditField = uieditfield(app.AddressTab, 'text');
            app.EndAddressEditField.Value = 'Platanenstr. 26, 82024, Taufkirchen';
            app.GetCoordinatesButton = uibutton(app.AddressTab, 'push');
            app.GetCoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @GetCoordinatesButtonPushed, true);
            app.GetCoordinatesButton.Text = 'Get Coordinates';
            app.ResetAddressButton = uibutton(app.AddressTab, 'push');
            app.ResetAddressButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAddressButtonPushed, true);
            app.ResetAddressButton.Text = 'Reset';
            app.FlipAddressButton = uibutton(app.AddressTab, 'push');
            app.FlipAddressButton.ButtonPushedFcn = createCallbackFcn(app, @FlipAddressButtonPushed, true);
            app.FlipAddressButton.Text = '↕';
            app.RoutingPanel = uipanel(app.UIFigure);
            app.RoutingPanel.Title = 'Routing';
            app.GetRouteButton = uibutton(app.RoutingPanel, 'push');
            app.GetRouteButton.ButtonPushedFcn = createCallbackFcn(app, @GetRouteButtonPushed, true);
            app.GetRouteButton.Text = 'Get Route';
            app.ResetRoutingButton = uibutton(app.RoutingPanel, 'push');
            app.ResetRoutingButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRoutingButtonPushed, true);
            app.ResetRoutingButton.Text = 'Reset';
            app.VehicleTypeDropDownLabel = uilabel(app.RoutingPanel);
            app.VehicleTypeDropDownLabel.Text = 'Vehicle Type';
            app.VehicleTypeDropDown = uidropdown(app.RoutingPanel);
            app.VehicleTypeDropDown.Items = {'Car'};
            app.VehicleTypeDropDown.Value = 'Car';
            app.IncludeWaypointsElevationCheckBox = uicheckbox(app.RoutingPanel);
            app.IncludeWaypointsElevationCheckBox.Text = 'Include Waypoints Elevation';
            app.IncludeWaypointsElevationCheckBox.Value = true;
            app.IncludeMaxSpeedCheckBox = uicheckbox(app.RoutingPanel);
            app.IncludeMaxSpeedCheckBox.Text = 'Include Max Speed';
            app.IncludeMaxSpeedCheckBox.Value = true;
            app.IncludeRoadClassCheckBox = uicheckbox(app.RoutingPanel);
            app.IncludeRoadClassCheckBox.Text = 'Include Road Class';
            app.IncludeRoadClassCheckBox.Value = true;
            app.IncludeLaneInformationCheckBox = uicheckbox(app.RoutingPanel);
            app.IncludeLaneInformationCheckBox.Text = 'Include Lane Information';
            app.IncludeLaneInformationCheckBox.Value = true;
            app.ExportFile = uibutton(app.RoutingPanel, 'push');
            app.ExportFile.ButtonPushedFcn = createCallbackFcn(app, @ExportFilePushed, true);
            app.ExportFile.Text = 'Export File';
            app.LanguageDropDownLabel = uilabel(app.UIFigure);
            app.LanguageDropDownLabel.Text = 'Language';
            app.LanguageDropDown = uidropdown(app.UIFigure);
            app.LanguageDropDown.Items = {'EN', 'DE'};
            app.LanguageDropDown.Value = 'EN';
            app.ApiKeyEditFieldLabel = uilabel(app.UIFigure);
            app.ApiKeyEditFieldLabel.Text = 'API Key';
            app.ApiKeyEditField = uieditfield(app.UIFigure, 'text');
            app.ApiKeyEditField.Value = '5eb3b6a-143f-4ad5-ae68-64883f65c1e5';
            app.ImportJSONDataPanel = uipanel(app.UIFigure);
            app.ImportJSONDataPanel.Title = 'Import JSON Data';
            app.FileEditFieldLabel = uilabel(app.ImportJSONDataPanel);
            app.FileEditFieldLabel.Text = 'File';
            app.ImportFileEditField = uieditfield(app.ImportJSONDataPanel, 'text');
            app.ImportFileEditField.Editable = 'off';
            app.SelectFileButton = uibutton(app.ImportJSONDataPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select';
            app.ImportFileButton = uibutton(app.ImportJSONDataPanel, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            app.ExportJSONDataPanel = uipanel(app.UIFigure);
            app.ExportJSONDataPanel.Title = 'Export JSON Data to Workspace';
            app.ExportDataCheckBox = uicheckbox(app.ExportJSONDataPanel);
            app.ExportDataCheckBox.Text = 'Export Data';
            app.ExportDataCheckBox.Value = true;
            app.VariableNameEditFieldLabel = uilabel(app.ExportJSONDataPanel);
            app.VariableNameEditFieldLabel.Text = 'Variable Name';
            app.ExportVariableEditField = uieditfield(app.ExportJSONDataPanel, 'text');
            app.ExportVariableEditField.ValueChangedFcn = createCallbackFcn(app, @ExportVariableEditFieldValueChanged, true);
            app.ExportVariableEditField.Value = 'routeDataStruct';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GH_GraphHopperGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end